classdef ManualGalvoControl_v0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SketchyGalvoControlUIFigure  matlab.ui.Figure
        ContinousModenotworkingforscanCheckBox  matlab.ui.control.CheckBox
        Amp1EditField                matlab.ui.control.NumericEditField
        Amp1EditFieldLabel           matlab.ui.control.Label
        ScanV1CheckBox               matlab.ui.control.CheckBox
        Amp0EditField                matlab.ui.control.NumericEditField
        Amp0EditFieldLabel           matlab.ui.control.Label
        ScanV0CheckBox               matlab.ui.control.CheckBox
        Increment0EditField          matlab.ui.control.EditField
        Increment0EditFieldLabel     matlab.ui.control.Label
        Increment1EditField          matlab.ui.control.EditField
        Increment1EditFieldLabel     matlab.ui.control.Label
        Voltage1Spinner              matlab.ui.control.Spinner
        Voltage1SpinnerLabel         matlab.ui.control.Label
        Voltage0Spinner              matlab.ui.control.Spinner
        Voltage0SpinnerLabel         matlab.ui.control.Label
        ClearButton                  matlab.ui.control.Button
        StartButton                  matlab.ui.control.Button
        ao1CheckBox                  matlab.ui.control.CheckBox
        ao0CheckBox                  matlab.ui.control.CheckBox
    end


    properties (Access = private)
        dq % ni daq
        voltage
        stopflag = false;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.voltage.ao0 = 1.62;
            app.voltage.ao1 = -0.1;
            app.dq = [];
            app.Voltage0Spinner.Step = 0.1;
            app.Voltage1Spinner.Step = 0.1;
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.stopflag = true;
        end

        % Value changed function: Increment0EditField
        function Increment0EditFieldValueChanged(app, event)

            app.Voltage0Spinner.Step = str2num(app.Increment0EditField.Value);
        end

        % Value changed function: Increment1EditField
        function Increment1EditFieldValueChanged(app, event)
            app.Voltage1Spinner.Step = str2num(app.Increment1EditField.Value);

        end

        % Value changed function: Voltage0Spinner
        function Voltage0SpinnerValueChanged(app, event)
            if app.ao0CheckBox.Value
                app.voltage.ao0 = app.Voltage0Spinner.Value;
            end
        end

        % Value changed function: Voltage1Spinner
        function Voltage1SpinnerValueChanged(app, event)
            if app.ao1CheckBox.Value
                app.voltage.ao1 = app.Voltage1Spinner.Value;
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            d = daq("ni");
            ch0 = 0;
            ch1 = 0;
            app.StartButton.Enable = 'off';
            addoutput(d,"Dev3","ao0","Voltage");
            addoutput(d,"Dev3","ao1","Voltage");
            if app.ao0CheckBox.Value
                ch0 = 1;
            end
            if app.ao1CheckBox.Value
                ch1 = 1;
            end

            amp0 = app.ScanV0CheckBox.Value*app.Amp0EditField.Value;
            amp1 = app.ScanV1CheckBox.Value*app.Amp1EditField.Value;
            signal0 = amp0*sin((1:1000)*2*pi/1000)+app.voltage.ao0;
            signal1 = amp1*cos((1:1000)*2*pi/1000)+app.voltage.ao1;
            signalAll = [signal0', signal1'];

            app.stopflag = 0;

            if ~app.ContinousModenotworkingforscanCheckBox.Value
                preload(d,signalAll)
                start(d,"RepeatOutput")
                while ~app.stopflag
                    pause(0.1)
                end
                stop(d)
            else
                d.Rate = 8000;
                while ~app.stopflag
                    write(d,[app.voltage.ao0*ch0 app.voltage.ao1*ch1]);
                    pause(0.1)
                end
            end

            
            clear d
            app.StartButton.Enable = 'on';
        end

        % Close request function: SketchyGalvoControlUIFigure
        function SketchyGalvoControlUIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SketchyGalvoControlUIFigure = uifigure('Visible', 'off');
            app.SketchyGalvoControlUIFigure.Name = 'SketchyGalvoControl';
            app.SketchyGalvoControlUIFigure.CloseRequestFcn = createCallbackFcn(app, @SketchyGalvoControlUIFigureCloseRequest, true);
            app.ao0CheckBox = uicheckbox(app.SketchyGalvoControlUIFigure);
            app.ao0CheckBox.Text = 'ao0';
            app.ao1CheckBox = uicheckbox(app.SketchyGalvoControlUIFigure);
            app.ao1CheckBox.Text = 'ao1';
            app.StartButton = uibutton(app.SketchyGalvoControlUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ClearButton = uibutton(app.SketchyGalvoControlUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.Voltage0SpinnerLabel = uilabel(app.SketchyGalvoControlUIFigure);
            app.Voltage0SpinnerLabel.Text = 'Voltage0';
            app.Voltage0Spinner = uispinner(app.SketchyGalvoControlUIFigure);
            app.Voltage0Spinner.Limits = [-5 5];
            app.Voltage0Spinner.ValueChangedFcn = createCallbackFcn(app, @Voltage0SpinnerValueChanged, true);
            app.Voltage0Spinner.Value = 1.62;
            app.Voltage1SpinnerLabel = uilabel(app.SketchyGalvoControlUIFigure);
            app.Voltage1SpinnerLabel.Text = 'Voltage1';
            app.Voltage1Spinner = uispinner(app.SketchyGalvoControlUIFigure);
            app.Voltage1Spinner.Limits = [-5 5];
            app.Voltage1Spinner.ValueChangedFcn = createCallbackFcn(app, @Voltage1SpinnerValueChanged, true);
            app.Voltage1Spinner.Value = -0.1;
            app.Increment1EditFieldLabel = uilabel(app.SketchyGalvoControlUIFigure);
            app.Increment1EditFieldLabel.Text = 'Increment1';
            app.Increment1EditField = uieditfield(app.SketchyGalvoControlUIFigure, 'text');
            app.Increment1EditField.ValueChangedFcn = createCallbackFcn(app, @Increment1EditFieldValueChanged, true);
            app.Increment1EditField.Value = '0.1';
            app.Increment0EditFieldLabel = uilabel(app.SketchyGalvoControlUIFigure);
            app.Increment0EditFieldLabel.Text = 'Increment0';
            app.Increment0EditField = uieditfield(app.SketchyGalvoControlUIFigure, 'text');
            app.Increment0EditField.ValueChangedFcn = createCallbackFcn(app, @Increment0EditFieldValueChanged, true);
            app.Increment0EditField.Value = '0.1';
            app.ScanV0CheckBox = uicheckbox(app.SketchyGalvoControlUIFigure);
            app.ScanV0CheckBox.Text = 'Scan V0';
            app.Amp0EditFieldLabel = uilabel(app.SketchyGalvoControlUIFigure);
            app.Amp0EditFieldLabel.Text = 'Amp0';
            app.Amp0EditField = uieditfield(app.SketchyGalvoControlUIFigure, 'numeric');
            app.ScanV1CheckBox = uicheckbox(app.SketchyGalvoControlUIFigure);
            app.ScanV1CheckBox.Text = 'Scan V1';
            app.Amp1EditFieldLabel = uilabel(app.SketchyGalvoControlUIFigure);
            app.Amp1EditFieldLabel.Text = 'Amp1';
            app.Amp1EditField = uieditfield(app.SketchyGalvoControlUIFigure, 'numeric');
            app.ContinousModenotworkingforscanCheckBox = uicheckbox(app.SketchyGalvoControlUIFigure);
            app.ContinousModenotworkingforscanCheckBox.Text = {'Continous Mode '; '(not working for scan)'};
            % Show the figure after all components are created
            app.SketchyGalvoControlUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManualGalvoControl_v0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SketchyGalvoControlUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SketchyGalvoControlUIFigure)
        end
    end
end