classdef Magnet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MagnetLoLevel              matlab.ui.Figure
        UpdateSwitch               matlab.ui.control.Switch
        UpdateSwitchLabel          matlab.ui.control.Label
        ThermalvoltageSwitch       matlab.ui.control.Switch
        ThermalvoltageSwitchLabel  matlab.ui.control.Label
        RampscheduleButton         matlab.ui.control.Button
        ZCoilPanel                 matlab.ui.container.Panel
        ZMode                      matlab.ui.control.EditField
        XMode_3Label               matlab.ui.control.Label
        ZCurrent                   matlab.ui.control.NumericEditField
        XCurrent_3Label            matlab.ui.control.Label
        ZVoltage                   matlab.ui.control.NumericEditField
        XVoltage_3Label            matlab.ui.control.Label
        ZLamp                      matlab.ui.control.Lamp
        ZOutputOnOff               matlab.ui.control.StateButton
        ZCurrentEdit               matlab.ui.control.NumericEditField
        XCurrentEditField_3Label   matlab.ui.control.Label
        ZVoltageEdit               matlab.ui.control.NumericEditField
        XVoltageEditField_3Label   matlab.ui.control.Label
        ZPolaritySwitch            matlab.ui.control.Switch
        PolaritySwitchLabel_3      matlab.ui.control.Label
        ConnectZ                   matlab.ui.control.StateButton
        YCoilPanel                 matlab.ui.container.Panel
        YPolaritySwitch            matlab.ui.control.Switch
        PolaritySwitchLabel_2      matlab.ui.control.Label
        YMode                      matlab.ui.control.EditField
        XMode_2Label               matlab.ui.control.Label
        YCurrent                   matlab.ui.control.NumericEditField
        XCurrent_2Label            matlab.ui.control.Label
        YVoltage                   matlab.ui.control.NumericEditField
        XVoltage_2Label            matlab.ui.control.Label
        YCurrentEdit               matlab.ui.control.NumericEditField
        XCurrentEditField_2Label   matlab.ui.control.Label
        YVoltageEdit               matlab.ui.control.NumericEditField
        XVoltageEditField_2Label   matlab.ui.control.Label
        YLamp                      matlab.ui.control.Lamp
        YOutputOnOff               matlab.ui.control.StateButton
        ConnectY                   matlab.ui.control.StateButton
        XCoilPanel                 matlab.ui.container.Panel
        XVoltage                   matlab.ui.control.NumericEditField
        VoltageEditFieldLabel      matlab.ui.control.Label
        XVoltageEdit               matlab.ui.control.NumericEditField
        VoltageSetEditFieldLabel   matlab.ui.control.Label
        XCurrentEdit               matlab.ui.control.NumericEditField
        CurrentSetEditFieldLabel   matlab.ui.control.Label
        XPolaritySwitch            matlab.ui.control.Switch
        PolaritySwitchLabel        matlab.ui.control.Label
        XMode                      matlab.ui.control.EditField
        ModeEditFieldLabel         matlab.ui.control.Label
        XCurrent                   matlab.ui.control.NumericEditField
        CurrentEditFieldLabel      matlab.ui.control.Label
        XLamp                      matlab.ui.control.Lamp
        XOutputOnOff               matlab.ui.control.StateButton
        ConnectX                   matlab.ui.control.StateButton
    end


    properties (Access = private)
    end

    properties (Access = public)
    end

    methods (Access = private)
    
        function UpdateX(app)
            if not(app.ConnectX.Value)
                app.XCurrent.Value=0;
                app.XVoltage.Value=0;
                app.XMode.Value='';
                app.XMode.BackgroundColor=[0.9373 0.9373 0.9373];
                return
            end
            global gMag;
            gMag.x.Query();
            app.XCurrent.Value= gMag.x.currentOut;
            app.XVoltage.Value= gMag.x.voltageOut;
            if isempty(gMag.x.mode)
                app.XMode.Value= '';
                app.XMode.BackgroundColor=[0.9373 0.9373 0.9373];
            else
                app.XMode.Value= gMag.x.mode;
                switch gMag.x.mode
                    case 'CC'
                        app.XMode.BackgroundColor='green';
                    case 'CV'
                        app.XMode.BackgroundColor='red';
                end
            end
        end
        
        function UpdateY(app)
            if not(app.ConnectY.Value)
                app.YCurrent.Value=0;
                app.YVoltage.Value=0;
                app.YMode.Value='';
                app.YMode.BackgroundColor=[0.9373 0.9373 0.9373];
                return
            end
            global gMag;
            gMag.y.Query();
            app.YCurrent.Value= gMag.y.currentOut;
            app.YVoltage.Value= gMag.y.voltageOut;
            if isempty(gMag.y.mode)
                app.YMode.Value= '';
                app.YMode.BackgroundColor=[0.9373 0.9373 0.9373];
            else
                app.YMode.Value= gMag.y.mode;
                switch gMag.y.mode
                    case 'CC'
                        app.YMode.BackgroundColor='green';
                    case 'CV'
                        app.YMode.BackgroundColor='red';
                end
            end
        end
        
        function UpdateZ(app)
            if not(app.ConnectZ.Value)
                app.ZCurrent.Value=0;
                app.ZVoltage.Value=0;
                app.ZMode.Value='';
                app.ZMode.BackgroundColor=[0.9373 0.9373 0.9373];
                return
            end
            global gMag;
            gMag.z.Query();
            app.ZCurrent.Value= gMag.z.currentOut;
            app.ZVoltage.Value= gMag.z.voltageOut;
            if isempty(gMag.z.mode)
                app.ZMode.Value= '';
                app.ZMode.BackgroundColor=[0.9373 0.9373 0.9373];
            else
                app.ZMode.Value= gMag.z.mode;
                switch gMag.z.mode
                    case 'CC'
                        app.ZMode.BackgroundColor= 'green';
                    case 'CV'
                        app.ZMode.BackgroundColor= 'red';
                end
            end
            %app.ZCurrentEdit.Value= gMag.z.current;
            %app.ZVoltageEdit.Value= gMag.z.voltage;
        end
        
    end

    methods (Access = public)
    
        function ChangeXI(app,value)
            app.XCurrentEdit.Value=value;
            XCurrentEditValueChanged(app,[]);
        end
        function ChangeYI(app,value)
            app.YCurrentEdit.Value=value;
            YCurrentEditValueChanged(app,[]);
        end
        function ChangeZI(app,value)
            app.ZCurrentEdit.Value=value;
            ZCurrentEditValueChanged(app,[]);
        end

        function RampRemote(app)
            RampscheduleButtonPushed(app,[]);
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global gMag
            gMag.ThermalVoltage=0;
            gMag.Rmax=4.4;
            disp("FUCK Yuanqi")
        end

        % Value changed function: ConnectX
        function ConnectXPushed(app, event)
            value = app.ConnectX.Value;
            global gMag;
            if value
                gMag.x= Keithley2200(PortMap('keithleyX'));
            else
                if app.XOutputOnOff.Value
                    app.ConnectX.Value=1;
                    error('Cannot disconnect X while the instrument is ON')
                else
                    gMag.x.EndSession();
                    delete(gMag.x);
                end
            end
        end

        % Value changed function: ConnectY
        function ConnectYPushed(app, event)
            value = app.ConnectY.Value;
            global gMag;
            if value
                gMag.y= Keithley2200(PortMap('keithleyY'));
            else
                if app.YOutputOnOff.Value
                    app.ConnectY.Value=1;
                    error('Cannot disconnect Y while the instrument is ON');
                else
                    gMag.y.EndSession();
                    delete(gMag.y);
                end
            end
        end

        % Value changed function: ConnectZ
        function ConnectZPushed(app, event)
            value = app.ConnectZ.Value;
            global gMag;
            if value
                gMag.z= Keithley2200(PortMap('keithleyZ'));
            else
                if app.ZOutputOnOff.Value
                    app.ConnectZ.Value=1;
                    error('Cannot disconnect Z while the instrument is ON');
                else
                    gMag.z.EndSession();
                    delete(gMag.z);
                end
            end
        end

        % Close request function: MagnetLoLevel
        function MagnetLoLevelCloseRequest(app, event)
            global gMag
            choice=questdlg('All instruments will be switched OFF. Proceed?',...
                            'Close Experiment',...
                            'Yes','No','No');
            if strcmp(choice,'No')
                return
            end
            if app.ConnectX.Value
                %gMag.x.OutputOnOff(0);
                gMag.x.EndSession();
                delete(gMag.x);
            end
            if app.ConnectY.Value
                %gMag.y.OutputOnOff(0);
                gMag.y.EndSession();
                delete(gMag.y);
            end
            if app.ConnectZ.Value
                %gMag.z.OutputOnOff(0);
                gMag.z.EndSession();
                delete(gMag.z);
            end
            delete(app);
            disp('Live long and prosper!!')
        end

        % Value changed function: XOutputOnOff
        function XOutputOnOffValueChanged(app, event)
            global gMag;
            value = app.XOutputOnOff.Value;
            if not(app.ConnectX.Value)
                app.XOutputOnOff.Value= not(value);
                updateXLamp();
                error('Not connected to the instrument X');
            end
            
            try
                gMag.x.OutputOnOff(value);
                updateXLamp();
            catch ME
                app.XOutputOnOff.Value=gMag.x.state;
                updateXLamp();
                rethrow(ME);
            end
            
            function updateXLamp()
                switch gMag.x.state
                    case 0
                        app.XLamp.Color=[0.9373 0.9373 0.9373];
                    case 1
                        app.XLamp.Color='green';
                end
            end
        end

        % Value changed function: YOutputOnOff
        function YOutputOnOffValueChanged(app, event)
            global gMag;
            value = app.YOutputOnOff.Value;
            
            if not(app.ConnectY.Value)
                app.YOutputOnOff.Value= not(value);
                updateYLamp();
                error('Not connected to the instrument Y');
            end
            
            try
                gMag.y.OutputOnOff(value);
                updateYLamp();
            catch ME
                app.YOutputOnOff.Value=gMag.y.state;
                updateYLamp();
                rethrow(ME);
            end
            
            function updateYLamp()
                switch gMag.y.state
                    case 0
                        app.YLamp.Color=[0.9373 0.9373 0.9373];
                    case 1
                        app.YLamp.Color='green';
                end
            end
        end

        % Value changed function: ZOutputOnOff
        function ZOutputOnOffValueChanged(app, event)
            global gMag;
            value = app.ZOutputOnOff.Value;
            if not(app.ConnectZ.Value)
                app.ZOutputOnOff.Value= not(value);
                updateZLamp(Z);
                error('Not connected to instrument Z');
            end
            
            try
                gMag.z.OutputOnOff(value);
                updateZLamp();
            catch ME
                app.ZOutputOnOff.Value=gMag.z.state;
                updateZLamp();
                rethrow(ME);
            end
            
            function updateZLamp()
                switch gMag.z.state
                    case 0
                        app.ZLamp.Color=[0.9373 0.9373 0.9373];
                    case 1
                        app.ZLamp.Color='green';
                end
            end
        end

        % Value changed function: XCurrentEdit
        function XCurrentEditValueChanged(app, event)
            value = app.XCurrentEdit.Value;
            if not(app.ConnectX.Value)
                error('Not connected to the instrument X');
            else
                global gMag;
                gMag.x.ISet(value);
                if gMag.ThermalVoltage
                    set(app.XVoltageEdit,'Value',value*gMag.Rmax);
                    gMag.x.VSet(value*gMag.Rmax);
                end
            end
        end

        % Value changed function: XVoltageEdit
        function XVoltageEditValueChanged(app, event)
            value = app.XVoltageEdit.Value;
            if not(app.ConnectX.Value)
                error('Not connected to the instrument X');
            else
                global gMag;
                gMag.x.VSet(value);
            end
        end

        % Value changed function: YCurrentEdit
        function YCurrentEditValueChanged(app, event)
            value = app.YCurrentEdit.Value;
            if not(app.ConnectY.Value)
                error('Not connected to the instrument Y');
            else
                global gMag;
                gMag.y.ISet(value);
                if gMag.ThermalVoltage
                    set(app.YVoltageEdit,'Value',value*gMag.Rmax);
                    gMag.y.VSet(value*gMag.Rmax);
                end
            end
        end

        % Value changed function: YVoltageEdit
        function YVoltageEditValueChanged(app, event)
            value = app.YVoltageEdit.Value;
            if not(app.ConnectY.Value)
                error('Not connected to the instrument Y');
            else
                global gMag;
                gMag.y.VSet(value);
            end            
        end

        % Value changed function: ZVoltageEdit
        function ZVoltageEditValueChanged(app, event)
            value = app.ZVoltageEdit.Value;
            if not(app.ConnectZ.Value)
                error('Not connected to the instrument Z');
            else
                global gMag;
                gMag.z.VSet(value);
            end            
        end

        % Value changed function: ZCurrentEdit
        function ZCurrentEditValueChanged(app, event)
            value = app.ZCurrentEdit.Value;
            if not(app.ConnectZ.Value)
                error('Not connected to the instrument Z');
            else
                global gMag;
                gMag.z.ISet(value);
                if gMag.ThermalVoltage
                    set(app.ZVoltageEdit,'Value',value*gMag.Rmax);
                    gMag.z.VSet(value*gMag.Rmax);
                end
            end
        end

        % Value changed function: ThermalvoltageSwitch
        function ThermalvoltageSwitchValueChanged(app, event)
            value = app.ThermalvoltageSwitch.Value;
            global gMag
            if strcmp(value,'Off')
                gMag.ThermalVoltage=0;
            else 
                gMag.ThermalVoltage=1;
            end
            if gMag.ThermalVoltage
                state='off';
                if app.ConnectX.Value   
                    gMag.x.VSet(app.XCurrentEdit.Value*gMag.Rmax);
                end
                if app.ConnectY.Value 
                    gMag.y.VSet(app.YCurrentEdit.Value*gMag.Rmax);
                end
                if app.ConnectZ.Value
                    gMag.z.VSet(app.ZCurrentEdit.Value*gMag.Rmax);
                end
                set(app.XVoltageEdit,'Value',app.XCurrentEdit.Value*gMag.Rmax);
                
                set(app.YVoltageEdit,'Value',app.YCurrentEdit.Value*gMag.Rmax);
                set(app.ZVoltageEdit,'Value',app.ZCurrentEdit.Value*gMag.Rmax);
            else 
                state='on';
            end
            set(app.XVoltageEdit, 'enable', state);
            set(app.YVoltageEdit, 'enable', state);
            set(app.ZVoltageEdit, 'enable', state);
        end

        % Value changed function: UpdateSwitch
        function UpdateSwitchValueChanged(app, event)
            value = app.UpdateSwitch.Value;
            
            while strcmp(value,'On')
                app.UpdateX();
                app.UpdateY();
                app.UpdateZ();
                pause(1.5);
                drawnow
                try
                    value= app.UpdateSwitch.Value;
                catch ME
                    switch ME.identifier
                        case 'MATLAB:class:InvalidHandle'
                            return;
                        otherwise 
                            rethrow(ME);
                    end
                end
            end
        end

        % Button pushed function: RampscheduleButton
        function RampscheduleButtonPushed(app, event)
           
            if not(app.ConnectY.Value) || not(app.ConnectY.Value) || not(app.ConnectZ.Value)
                error('All coils must be connected to perform ramp schedule.')
            end
            global gMag
            gMag.x.Query();
            gMag.y.Query();
            gMag.z.Query();
            xcurrent= gMag.x.currentSet;
            xvoltage= gMag.x.voltageSet;            
            ycurrent= gMag.y.currentSet;
            yvoltage= gMag.y.voltageSet;
            zcurrent= gMag.z.currentSet;
            zvoltage= gMag.z.voltageSet;
            gMag.x.VSet(3.5*gMag.Rmax);
            gMag.y.VSet(3.5*gMag.Rmax);
            gMag.z.VSet(3.5*gMag.Rmax);
            
            gMag.x.ISet(0);
            gMag.y.ISet(0);
            gMag.z.ISet(0);
            pause(.5);
            gMag.x.ISet(3.5);
            gMag.y.ISet(3.5);
            gMag.z.ISet(3.5);
            pause(.5);
            gMag.x.ISet(0);
            gMag.y.ISet(0);
            gMag.z.ISet(0);
            pause(.5);
            gMag.x.ISet(3.5);
            gMag.y.ISet(3.5);
            gMag.z.ISet(3.5);
            pause(.5);
            gMag.x.ISet(xcurrent);
            gMag.y.ISet(ycurrent);
            gMag.z.ISet(zcurrent);
            
            gMag.x.VSet(xvoltage);
            gMag.y.VSet(yvoltage);
            gMag.z.VSet(zvoltage);
            disp(['Ramp complete at (' , num2str(xcurrent),', ',num2str(ycurrent),', ',num2str(zcurrent),')! Enjoy your magnetic field!'])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MagnetLoLevel = uifigure('Visible', 'off');
            app.MagnetLoLevel.Name = 'Magnet Low Level';
            app.MagnetLoLevel.CloseRequestFcn = createCallbackFcn(app, @MagnetLoLevelCloseRequest, true);
            app.XCoilPanel = uipanel(app.MagnetLoLevel);
            app.XCoilPanel.Title = 'X Coil';
            app.ConnectX = uibutton(app.XCoilPanel, 'state');
            app.ConnectX.ValueChangedFcn = createCallbackFcn(app, @ConnectXPushed, true);
            app.ConnectX.Text = 'Connect X';
            app.XOutputOnOff = uibutton(app.XCoilPanel, 'state');
            app.XOutputOnOff.ValueChangedFcn = createCallbackFcn(app, @XOutputOnOffValueChanged, true);
            app.XOutputOnOff.Text = 'Output On/Off';
            app.XLamp = uilamp(app.XCoilPanel);
            app.CurrentEditFieldLabel = uilabel(app.XCoilPanel);
            app.CurrentEditFieldLabel.Text = 'Current';
            app.XCurrent = uieditfield(app.XCoilPanel, 'numeric');
            app.XCurrent.Limits = [0 5];
            app.XCurrent.ValueDisplayFormat = '%.4f';
            app.XCurrent.Editable = 'off';
            app.ModeEditFieldLabel = uilabel(app.XCoilPanel);
            app.ModeEditFieldLabel.Text = 'Mode';
            app.XMode = uieditfield(app.XCoilPanel, 'text');
            app.XMode.Editable = 'off';
            app.PolaritySwitchLabel = uilabel(app.XCoilPanel);
            app.PolaritySwitchLabel.Text = 'Polarity';
            app.XPolaritySwitch = uiswitch(app.XCoilPanel, 'slider');
            app.XPolaritySwitch.Items = {'(-)', '(+)'};
            app.XPolaritySwitch.Value = '(-)';
            app.CurrentSetEditFieldLabel = uilabel(app.XCoilPanel);
            app.CurrentSetEditFieldLabel.Text = 'Current Set';
            app.XCurrentEdit = uieditfield(app.XCoilPanel, 'numeric');
            app.XCurrentEdit.Limits = [0 5];
            app.XCurrentEdit.ValueDisplayFormat = '%.4f';
            app.XCurrentEdit.ValueChangedFcn = createCallbackFcn(app, @XCurrentEditValueChanged, true);
            app.VoltageSetEditFieldLabel = uilabel(app.XCoilPanel);
            app.VoltageSetEditFieldLabel.Text = 'Voltage Set';
            app.XVoltageEdit = uieditfield(app.XCoilPanel, 'numeric');
            app.XVoltageEdit.Limits = [0 30];
            app.XVoltageEdit.ValueDisplayFormat = '%.3f';
            app.XVoltageEdit.ValueChangedFcn = createCallbackFcn(app, @XVoltageEditValueChanged, true);
            app.VoltageEditFieldLabel = uilabel(app.XCoilPanel);
            app.VoltageEditFieldLabel.Text = 'Voltage';
            app.XVoltage = uieditfield(app.XCoilPanel, 'numeric');
            app.XVoltage.Limits = [0 30];
            app.XVoltage.ValueDisplayFormat = '%.3f';
            app.XVoltage.Editable = 'off';
            app.YCoilPanel = uipanel(app.MagnetLoLevel);
            app.YCoilPanel.Title = 'Y Coil';
            app.ConnectY = uibutton(app.YCoilPanel, 'state');
            app.ConnectY.ValueChangedFcn = createCallbackFcn(app, @ConnectYPushed, true);
            app.ConnectY.Text = 'Connect Y';
            app.YOutputOnOff = uibutton(app.YCoilPanel, 'state');
            app.YOutputOnOff.ValueChangedFcn = createCallbackFcn(app, @YOutputOnOffValueChanged, true);
            app.YOutputOnOff.Text = 'Output On/Off';
            app.YLamp = uilamp(app.YCoilPanel);
            app.XVoltageEditField_2Label = uilabel(app.YCoilPanel);
            app.XVoltageEditField_2Label.Text = 'Voltage Set';
            app.YVoltageEdit = uieditfield(app.YCoilPanel, 'numeric');
            app.YVoltageEdit.Limits = [0 30];
            app.YVoltageEdit.ValueDisplayFormat = '%.3f';
            app.YVoltageEdit.ValueChangedFcn = createCallbackFcn(app, @YVoltageEditValueChanged, true);
            app.XCurrentEditField_2Label = uilabel(app.YCoilPanel);
            app.XCurrentEditField_2Label.Text = 'Current Set';
            app.YCurrentEdit = uieditfield(app.YCoilPanel, 'numeric');
            app.YCurrentEdit.Limits = [0 5];
            app.YCurrentEdit.ValueDisplayFormat = '%.4f';
            app.YCurrentEdit.ValueChangedFcn = createCallbackFcn(app, @YCurrentEditValueChanged, true);
            app.XVoltage_2Label = uilabel(app.YCoilPanel);
            app.XVoltage_2Label.Text = 'Voltage';
            app.YVoltage = uieditfield(app.YCoilPanel, 'numeric');
            app.YVoltage.Limits = [0 30];
            app.YVoltage.ValueDisplayFormat = '%.3f';
            app.YVoltage.Editable = 'off';
            app.XCurrent_2Label = uilabel(app.YCoilPanel);
            app.XCurrent_2Label.Text = 'Current';
            app.YCurrent = uieditfield(app.YCoilPanel, 'numeric');
            app.YCurrent.Limits = [0 5];
            app.YCurrent.ValueDisplayFormat = '%.4f';
            app.YCurrent.Editable = 'off';
            app.XMode_2Label = uilabel(app.YCoilPanel);
            app.XMode_2Label.Text = 'Mode';
            app.YMode = uieditfield(app.YCoilPanel, 'text');
            app.YMode.Editable = 'off';
            app.PolaritySwitchLabel_2 = uilabel(app.YCoilPanel);
            app.PolaritySwitchLabel_2.Text = 'Polarity';
            app.YPolaritySwitch = uiswitch(app.YCoilPanel, 'slider');
            app.YPolaritySwitch.Items = {'(-)', '(+)'};
            app.YPolaritySwitch.Value = '(-)';
            app.ZCoilPanel = uipanel(app.MagnetLoLevel);
            app.ZCoilPanel.Title = 'Z Coil';
            app.ConnectZ = uibutton(app.ZCoilPanel, 'state');
            app.ConnectZ.ValueChangedFcn = createCallbackFcn(app, @ConnectZPushed, true);
            app.ConnectZ.Text = 'Connect Z';
            app.PolaritySwitchLabel_3 = uilabel(app.ZCoilPanel);
            app.PolaritySwitchLabel_3.Text = 'Polarity';
            app.ZPolaritySwitch = uiswitch(app.ZCoilPanel, 'slider');
            app.ZPolaritySwitch.Items = {'(-)', '(+)'};
            app.ZPolaritySwitch.Value = '(-)';
            app.XVoltageEditField_3Label = uilabel(app.ZCoilPanel);
            app.XVoltageEditField_3Label.Text = 'Voltage Set';
            app.ZVoltageEdit = uieditfield(app.ZCoilPanel, 'numeric');
            app.ZVoltageEdit.Limits = [0 30];
            app.ZVoltageEdit.ValueDisplayFormat = '%.3f';
            app.ZVoltageEdit.ValueChangedFcn = createCallbackFcn(app, @ZVoltageEditValueChanged, true);
            app.XCurrentEditField_3Label = uilabel(app.ZCoilPanel);
            app.XCurrentEditField_3Label.Text = 'Current Set';
            app.ZCurrentEdit = uieditfield(app.ZCoilPanel, 'numeric');
            app.ZCurrentEdit.Limits = [0 5];
            app.ZCurrentEdit.ValueDisplayFormat = '%.4f';
            app.ZCurrentEdit.ValueChangedFcn = createCallbackFcn(app, @ZCurrentEditValueChanged, true);
            app.ZOutputOnOff = uibutton(app.ZCoilPanel, 'state');
            app.ZOutputOnOff.ValueChangedFcn = createCallbackFcn(app, @ZOutputOnOffValueChanged, true);
            app.ZOutputOnOff.Text = 'Output On/Off';
            app.ZLamp = uilamp(app.ZCoilPanel);
            app.XVoltage_3Label = uilabel(app.ZCoilPanel);
            app.XVoltage_3Label.Text = 'Voltage';
            app.ZVoltage = uieditfield(app.ZCoilPanel, 'numeric');
            app.ZVoltage.Limits = [0 30];
            app.ZVoltage.ValueDisplayFormat = '%.3f';
            app.ZVoltage.Editable = 'off';
            app.XCurrent_3Label = uilabel(app.ZCoilPanel);
            app.XCurrent_3Label.Text = 'Current';
            app.ZCurrent = uieditfield(app.ZCoilPanel, 'numeric');
            app.ZCurrent.Limits = [0 5];
            app.ZCurrent.ValueDisplayFormat = '%.4f';
            app.ZCurrent.Editable = 'off';
            app.XMode_3Label = uilabel(app.ZCoilPanel);
            app.XMode_3Label.Text = 'Mode';
            app.ZMode = uieditfield(app.ZCoilPanel, 'text');
            app.ZMode.Editable = 'off';
            app.RampscheduleButton = uibutton(app.MagnetLoLevel, 'push');
            app.RampscheduleButton.ButtonPushedFcn = createCallbackFcn(app, @RampscheduleButtonPushed, true);
            app.RampscheduleButton.Text = 'Ramp schedule';
            app.ThermalvoltageSwitchLabel = uilabel(app.MagnetLoLevel);
            app.ThermalvoltageSwitchLabel.Text = 'Thermal voltage?';
            app.ThermalvoltageSwitch = uiswitch(app.MagnetLoLevel, 'slider');
            app.ThermalvoltageSwitch.ValueChangedFcn = createCallbackFcn(app, @ThermalvoltageSwitchValueChanged, true);
            app.UpdateSwitchLabel = uilabel(app.MagnetLoLevel);
            app.UpdateSwitchLabel.Text = 'Update';
            app.UpdateSwitch = uiswitch(app.MagnetLoLevel, 'slider');
            app.UpdateSwitch.ValueChangedFcn = createCallbackFcn(app, @UpdateSwitchValueChanged, true);
            % Show the figure after all components are created
            app.MagnetLoLevel.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Magnet

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MagnetLoLevel)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MagnetLoLevel)
        end
    end
end