classdef MagnetHiLevel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MagnetHighLevelUIFigure      matlab.ui.Figure
        AngleEditField               matlab.ui.control.NumericEditField
        AngleEditFieldLabel          matlab.ui.control.Label
        StatusTextArea               matlab.ui.control.TextArea
        StatusTextAreaLabel          matlab.ui.control.Label
        OffsetwithrespecttocalibrationaxesPanel  matlab.ui.container.Panel
        OffsetphiEditField           matlab.ui.control.NumericEditField
        OffsetphiEditFieldLabel      matlab.ui.control.Label
        OffsetthetaEditField         matlab.ui.control.NumericEditField
        OffsetthetaEditFieldLabel    matlab.ui.control.Label
        RampscheduleButton           matlab.ui.control.Button
        PhiEditField                 matlab.ui.control.NumericEditField
        PhiradEditFieldLabel         matlab.ui.control.Label
        ThetaEditField               matlab.ui.control.NumericEditField
        ThetaradEditFieldLabel       matlab.ui.control.Label
        B0EditField                  matlab.ui.control.NumericEditField
        B0GEditFieldLabel            matlab.ui.control.Label
        SendtocoilsButton            matlab.ui.control.Button
        CalculateButton              matlab.ui.control.Button
        AxisDropDown                 matlab.ui.control.DropDown
        toLabel                      matlab.ui.control.Label
        DirectionDropDown            matlab.ui.control.DropDown
        GenerateBfieldDropDownLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end


    properties (Access = public)
        MagnetGUI=Magnet; % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global gMag
            gMag.hAtom=MagnetHiLevelFunctionPool('PlotInit',app.UIAxes);
            gMag.B=[1 0 0];
%             gMag.a_mn=   [    9.1433  -18.7584   22.1439
%   -24.4658   11.2319   25.1413
%     0.0885    7.0439  -66.7951];
        end

        % Value changed function: AxisDropDown
        function AxisDropDownValueChanged(app, event)
            value = app.AxisDropDown.Value;
            if strcmp(value,'')
                return
            end
            global gMag
            if strcmp(app.DirectionDropDown.Value,'Parallel')
                    perp=0;
                    set(app.AngleEditField,'enable','off');
            else
                    perp=1;
                    set(app.AngleEditField,'enable','on');
                    perpangle=app.AngleEditField.Value;
            end
            
            if strcmp(value,'Custom axis')
                state='on';
                gMag.B(2)=app.ThetaEditField.Value;
                gMag.B(3)=app.PhiEditField.Value;
                if perp
                    [gMag.B(2),gMag.B(3)]=MagnetHiLevelFunctionPool('RotateArb',gMag.B(2)+(pi/2),gMag.B(3),gMag.B(2),gMag.B(3),perpangle/180*pi);
                end
            else
                state='off';
                
                [gMag.B(2), gMag.B(3)]=MagnetHiLevelFunctionPool('PresetAxis',value);
                if perp
                    [gMag.B(2),gMag.B(3)]=MagnetHiLevelFunctionPool('RotateArb',gMag.B(2)+(pi/2),gMag.B(3),gMag.B(2),gMag.B(3),perpangle/180*pi);
                end
                if isfield(gMag,'hMag')
                    gMag.hMag=MagnetHiLevelFunctionPool('PlotMag',gMag.B,app.UIAxes,gMag.hAtom,gMag.hMag);
                else
                    gMag.hMag=MagnetHiLevelFunctionPool('PlotMag',gMag.B,app.UIAxes,gMag.hAtom);
                end
                app.ThetaEditField.Value=gMag.B(2);
                app.PhiEditField.Value=gMag.B(3);
            end
            
            set(app.ThetaEditField, 'enable', state);
            set(app.PhiEditField, 'enable', state);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            if strcmp(app.AxisDropDown.Value,'')
                return
            end
            global gMag
            gMag.B(1)=app.B0EditField.Value;
            AxisDropDownValueChanged(app,[]);
            if strcmp(app.AxisDropDown.Value,'Custom axis')
                if isfield(gMag,'hMag')
                    gMag.hMag=MagnetHiLevelFunctionPool('PlotMag',gMag.B,app.UIAxes,gMag.hAtom,gMag.hMag);
                else
                    gMag.hMag=MagnetHiLevelFunctionPool('PlotMag',gMag.B,app.UIAxes,gMag.hAtom);
                end
            end
            gMag.i=MagnetHiLevelFunctionPool('CalculateCurrent',gMag.B);
            text=['The currents to apply are (', num2str(gMag.i'), ').'];
            set(app.StatusTextArea,'Value',text);
        end

        % Value changed function: DirectionDropDown
        function DirectionDropDownValueChanged(app, event)
            value = app.DirectionDropDown.Value;
            AxisDropDownValueChanged(app,[]);
        end

        % Button pushed function: SendtocoilsButton
        function SendtocoilsButtonPushed(app, event)
            global gMag
%             gMag.polarity;
%             if ~isequal(sign(gMag.i),gMag.polarity)||~isequal(-sign(gMag.i),gMag.polarity)
%                 % turn off the outputs
%                 app.lowLevel = Magnet;
%                 app.lowLevel.XOutputOnOffChange(app,0);
%                 choice = questdlg(['Outputs have been turned off. Please rearrange power supply polarities to be ',num2str(sign(gMag.i)),' or the negation of this.'], ...
% 	       'Polarities incorrect', ...
% 	       'OK','OK, set to negation','Cancel');
%                 if strcmp(choice,'Cancel')
%                     return
%                 end
%             end
%             gMag.polarity=sign(gMag.i);
            % send new polarities to Magnet Low Level
            iunsigned=abs(gMag.i);
            ChangeXI(app.MagnetGUI,iunsigned(1));
            ChangeYI(app.MagnetGUI,iunsigned(2));
            ChangeZI(app.MagnetGUI,iunsigned(3));
            textold=app.StatusTextArea.Value;
            textnew=sprintf([char(textold), '\n\nCurrents sent to coils!']);
            set(app.StatusTextArea,'Value',textnew);
        end

        % Callback function
        function AngleSliderValueChanged(app, event)
            value = app.AngleSlider.Value;
            AxisDropDownValueChanged(app,[]);
        end

        % Value changed function: AngleEditField
        function AngleEditFieldValueChanged(app, event)
            value = app.AngleEditField.Value;
            
        end

        % Button pushed function: RampscheduleButton
        function RampscheduleButtonPushed(app, event)
            RampRemote(app.MagnetGUI);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MagnetHighLevelUIFigure = uifigure('Visible', 'off');
            app.MagnetHighLevelUIFigure.Name = 'Magnet High Level';
            app.UIAxes = uiaxes(app.MagnetHighLevelUIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.GenerateBfieldDropDownLabel = uilabel(app.MagnetHighLevelUIFigure);
            app.GenerateBfieldDropDownLabel.Text = 'Generate B field...';
            app.DirectionDropDown = uidropdown(app.MagnetHighLevelUIFigure);
            app.DirectionDropDown.Items = {'Parallel', 'Perpendicular'};
            app.DirectionDropDown.ValueChangedFcn = createCallbackFcn(app, @DirectionDropDownValueChanged, true);
            app.DirectionDropDown.Value = 'Parallel';
            app.toLabel = uilabel(app.MagnetHighLevelUIFigure);
            app.toLabel.Text = 'to...';
            app.AxisDropDown = uidropdown(app.MagnetHighLevelUIFigure);
            app.AxisDropDown.Items = {'', '111', '-1-11', '-11-1', '1-1-1', 'Custom axis'};
            app.AxisDropDown.ValueChangedFcn = createCallbackFcn(app, @AxisDropDownValueChanged, true);
            app.AxisDropDown.Value = '';
            app.CalculateButton = uibutton(app.MagnetHighLevelUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.SendtocoilsButton = uibutton(app.MagnetHighLevelUIFigure, 'push');
            app.SendtocoilsButton.ButtonPushedFcn = createCallbackFcn(app, @SendtocoilsButtonPushed, true);
            app.SendtocoilsButton.Text = 'Send to coils';
            app.B0GEditFieldLabel = uilabel(app.MagnetHighLevelUIFigure);
            app.B0GEditFieldLabel.Text = 'B0 (G)';
            app.B0EditField = uieditfield(app.MagnetHighLevelUIFigure, 'numeric');
            app.B0EditField.Limits = [0 Inf];
            app.B0EditField.Value = 1;
            app.ThetaradEditFieldLabel = uilabel(app.MagnetHighLevelUIFigure);
            app.ThetaradEditFieldLabel.Text = 'Theta (rad)';
            app.ThetaEditField = uieditfield(app.MagnetHighLevelUIFigure, 'numeric');
            app.PhiradEditFieldLabel = uilabel(app.MagnetHighLevelUIFigure);
            app.PhiradEditFieldLabel.Text = 'Phi (rad)';
            app.PhiEditField = uieditfield(app.MagnetHighLevelUIFigure, 'numeric');
            app.RampscheduleButton = uibutton(app.MagnetHighLevelUIFigure, 'push');
            app.RampscheduleButton.ButtonPushedFcn = createCallbackFcn(app, @RampscheduleButtonPushed, true);
            app.RampscheduleButton.Text = 'Ramp schedule';
            app.OffsetwithrespecttocalibrationaxesPanel = uipanel(app.MagnetHighLevelUIFigure);
            app.OffsetwithrespecttocalibrationaxesPanel.Title = 'Offset with respect to calibration axes';
            app.OffsetthetaEditFieldLabel = uilabel(app.OffsetwithrespecttocalibrationaxesPanel);
            app.OffsetthetaEditFieldLabel.Text = 'Offset theta';
            app.OffsetthetaEditField = uieditfield(app.OffsetwithrespecttocalibrationaxesPanel, 'numeric');
            app.OffsetphiEditFieldLabel = uilabel(app.OffsetwithrespecttocalibrationaxesPanel);
            app.OffsetphiEditFieldLabel.Text = 'Offset phi';
            app.OffsetphiEditField = uieditfield(app.OffsetwithrespecttocalibrationaxesPanel, 'numeric');
            app.StatusTextAreaLabel = uilabel(app.MagnetHighLevelUIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.MagnetHighLevelUIFigure);
            app.StatusTextArea.Editable = 'off';
            app.StatusTextArea.Value = {'Standing by at your command...'};
            app.AngleEditFieldLabel = uilabel(app.MagnetHighLevelUIFigure);
            app.AngleEditFieldLabel.Enable = 'off';
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.MagnetHighLevelUIFigure, 'numeric');
            app.AngleEditField.ValueChangedFcn = createCallbackFcn(app, @AngleEditFieldValueChanged, true);
            app.AngleEditField.Enable = 'off';
            % Show the figure after all components are created
            app.MagnetHighLevelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MagnetHiLevel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MagnetHighLevelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MagnetHighLevelUIFigure)
        end
    end
end