classdef Magnet_New < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MagnetLoLevel              matlab.ui.Figure
        LogPathEditField           matlab.ui.control.EditField
        LogPathEditFieldLabel      matlab.ui.control.Label
        UpdateSwitch               matlab.ui.control.Switch
        UpdateSwitchLabel          matlab.ui.control.Label
        ThermalvoltageSwitch       matlab.ui.control.Switch
        ThermalvoltageSwitchLabel  matlab.ui.control.Label
        RampscheduleButton         matlab.ui.control.Button
        ZCoilPanel                 matlab.ui.container.Panel
        ZMode                      matlab.ui.control.EditField
        XMode_3Label               matlab.ui.control.Label
        ZCurrent                   matlab.ui.control.NumericEditField
        XCurrent_3Label            matlab.ui.control.Label
        ZVoltage                   matlab.ui.control.NumericEditField
        XVoltage_3Label            matlab.ui.control.Label
        ZLamp                      matlab.ui.control.Lamp
        ZOutputOnOff               matlab.ui.control.StateButton
        ZCurrentEdit               matlab.ui.control.NumericEditField
        XCurrentEditField_3Label   matlab.ui.control.Label
        ZVoltageEdit               matlab.ui.control.NumericEditField
        XVoltageEditField_3Label   matlab.ui.control.Label
        ZPolaritySwitch            matlab.ui.control.Switch
        PolaritySwitchLabel_3      matlab.ui.control.Label
        ConnectZ                   matlab.ui.control.StateButton
        YCoilPanel                 matlab.ui.container.Panel
        YPolaritySwitch            matlab.ui.control.Switch
        PolaritySwitchLabel_2      matlab.ui.control.Label
        YMode                      matlab.ui.control.EditField
        XMode_2Label               matlab.ui.control.Label
        YCurrent                   matlab.ui.control.NumericEditField
        XCurrent_2Label            matlab.ui.control.Label
        YVoltage                   matlab.ui.control.NumericEditField
        XVoltage_2Label            matlab.ui.control.Label
        YCurrentEdit               matlab.ui.control.NumericEditField
        XCurrentEditField_2Label   matlab.ui.control.Label
        YVoltageEdit               matlab.ui.control.NumericEditField
        XVoltageEditField_2Label   matlab.ui.control.Label
        YLamp                      matlab.ui.control.Lamp
        YOutputOnOff               matlab.ui.control.StateButton
        ConnectY                   matlab.ui.control.StateButton
        XCoilPanel                 matlab.ui.container.Panel
        XVoltage                   matlab.ui.control.NumericEditField
        VoltageEditFieldLabel      matlab.ui.control.Label
        XVoltageEdit               matlab.ui.control.NumericEditField
        VoltageSetEditFieldLabel   matlab.ui.control.Label
        XCurrentEdit               matlab.ui.control.NumericEditField
        CurrentSetEditFieldLabel   matlab.ui.control.Label
        XPolaritySwitch            matlab.ui.control.Switch
        PolaritySwitchLabel        matlab.ui.control.Label
        XMode                      matlab.ui.control.EditField
        ModeEditFieldLabel         matlab.ui.control.Label
        XCurrent                   matlab.ui.control.NumericEditField
        CurrentEditFieldLabel      matlab.ui.control.Label
        XLamp                      matlab.ui.control.Lamp
        XOutputOnOff               matlab.ui.control.StateButton
        ConnectX                   matlab.ui.control.StateButton
    end


    properties (Access = private)
    end

    properties (Access = public)
        Magn
        Timer
        Log_Path
    end

    methods (Access = private)
    
        function UpdateX(app)
            if not(app.ConnectX.Value)
                app.XCurrent.Value = 0;
                app.XVoltage.Value = 0;
                app.XMode.Value = '';
                app.XMode.BackgroundColor=[0.9373 0.9373 0.9373];
                return
            end
            
            app.Magn.x.Query();
            app.XCurrent.Value = app.Magn.x.currentOut;
            app.XVoltage.Value = app.Magn.x.voltageOut;
            if isempty(app.Magn.x.mode)
                app.XMode.Value= '';
                app.XMode.BackgroundColor = [0.9373 0.9373 0.9373];
            else
                app.XMode.Value= app.Magn.x.mode;
                switch app.Magn.x.mode
                    case 'CC'
                        app.XMode.BackgroundColor='green';
                    case 'CV'
                        app.XMode.BackgroundColor='red';
                end
            end
        end
        
        function UpdateY(app)
            if not(app.ConnectY.Value)
                app.YCurrent.Value=0;
                app.YVoltage.Value=0;
                app.YMode.Value='';
                app.YMode.BackgroundColor=[0.9373 0.9373 0.9373];
                return
            end
            
            app.Magn.y.Query();
            app.YCurrent.Value= app.Magn.y.currentOut;
            app.YVoltage.Value= app.Magn.y.voltageOut;
            if isempty(app.Magn.y.mode)
                app.YMode.Value= '';
                app.YMode.BackgroundColor=[0.9373 0.9373 0.9373];
            else
                app.YMode.Value= app.Magn.y.mode;
                switch app.Magn.y.mode
                    case 'CC'
                        app.YMode.BackgroundColor='green';
                    case 'CV'
                        app.YMode.BackgroundColor='red';
                end
            end
        end
        
        function UpdateZ(app)
            if not(app.ConnectZ.Value)
                app.ZCurrent.Value=0;
                app.ZVoltage.Value=0;
                app.ZMode.Value='';
                app.ZMode.BackgroundColor=[0.9373 0.9373 0.9373];
                return
            end
            
            app.Magn.z.Query();
            app.ZCurrent.Value= app.Magn.z.currentOut;
            app.ZVoltage.Value= app.Magn.z.voltageOut;
            if isempty(app.Magn.z.mode)
                app.ZMode.Value= '';
                app.ZMode.BackgroundColor=[0.9373 0.9373 0.9373];
            else
                app.ZMode.Value= app.Magn.z.mode;
                switch app.Magn.z.mode
                    case 'CC'
                        app.ZMode.BackgroundColor= 'green';
                    case 'CV'
                        app.ZMode.BackgroundColor= 'red';
                end
            end
            %app.ZCurrentEdit.Value= app.Magn.z.current;
            %app.ZVoltageEdit.Value= app.Magn.z.voltage;
        end
        
        function timerFunc(app, ~, ~)
            app.Log_Path = app.LogPathEditField.Value;
            if ~isfolder(app.Log_Path)
                msgboxStyle.Interpreter = 'tex';
                msgboxStyle.WindowStyle = 'modal';
                msgbox({'\fontsize{10}{\bfFolder does not exist!}', ... 
                    'Creating the folder now!'}, ...
                    'ERROR - Magnet', 'warn', msgboxStyle);
                try 
                    mkdir(app.Log_Path)
                catch errMsg
                    app.Log_Path = pwd;
                    app.LogPathEditField.Value ...
                        = app.Log_Path;
                    msgbox({['\fontsize{10}{\bfFolder cannot be! ', ... 
                        'created!}'], ...
                        'Data will be stored in current folder'}, ...
                        'ERROR - Magnet', 'error', msgboxStyle);
                    warning(errMsg.message)
                end
            end
            %%%
            % Create the log file path.
            path = fullfile(app.Log_Path, ...
                [datestr(now, 'yyyy-mm-dd'), '.txt']);
            if ~isfile(path)
                
                head = ["DateTime", "X_Current_A", "Y_Current_A", "Z_Current_A"];
                fID = fopen(path, 'w');
                fprintf(fID, '%s\t%s\t%s\t%s\r\n', head);
                fclose(fID);
                %%%
                % Create the file and write the head line.
                
            end
            app.UpdateX();
            app.UpdateY();
            app.UpdateZ();
            pause(2);
            
            data = [now, ...
                app.Magn.x.currentOut, app.Magn.y.currentOut, app.Magn.z.currentOut];
            
            fID = fopen(path, 'a');
            fprintf(fID, '%.5f\t%.3f\t%.3f\t%.3f\r\n', data);
            fclose(fID);
        end
        
        function timerErrFunc(~, ~, ~)
            msgboxStyle.Interpreter = 'tex';
            msgboxStyle.WindowStyle = 'modal';
            msgbox({'\fontsize{10}{\bfAcquization Failed!}', ... 
                'Timer will stop now!'}, ...
                'ERROR - Magnet', 'error', msgboxStyle);
        end
        function timerStopFunc(~, ~, ~)
            msgboxStyle.Interpreter = 'tex';
            msgboxStyle.WindowStyle = 'modal';
            msgbox({'\fontsize{10}{\bfAcquization Stopped!}', ... 
                'No more updates!'}, ...
                'ERROR - Magnet', 'warn', msgboxStyle);
        end
    end

    methods (Access = public)
    
        function ChangeXI(app,value)
            app.XCurrentEdit.Value=value;
            XCurrentEditValueChanged(app,[]);
        end
        function ChangeYI(app,value)
            app.YCurrentEdit.Value=value;
            YCurrentEditValueChanged(app,[]);
        end
        function ChangeZI(app,value)
            app.ZCurrentEdit.Value=value;
            ZCurrentEditValueChanged(app,[]);
        end

        function RampRemote(app)
            RampscheduleButtonPushed(app,[]);
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Magn.ThermalVoltage = 0;
            app.Magn.Rmax = 4.4;
            app.Timer = timer( ...
                    'Period', 5, ...
                    'ExecutionMode', 'fixedRate');
            app.Timer.TimerFcn = @app.timerFunc;
            app.Timer.ErrorFcn = @app.timerErrFunc;
            app.Timer.StopFcn = @app.timerStopFunc;
        end

        % Value changed function: ConnectX
        function ConnectXPushed(app, event)
            if app.ConnectX.Value
                app.Magn.x = Keithley2200(PortMap('keithleyX'));
            else
                if app.XOutputOnOff.Value
                    app.ConnectX.Value=1;
                    error('Cannot disconnect X while the instrument is ON')
                else
                    app.Magn.x.EndSession();
                    app.Magn = rmfield(app.Magn, 'x');
                end
            end
        end

        % Value changed function: ConnectY
        function ConnectYPushed(app, event)
            if app.ConnectY.Value
                app.Magn.y = Keithley2200(PortMap('keithleyY'));
            else
                if app.YOutputOnOff.Value
                    app.ConnectY.Value=1;
                    error('Cannot disconnect Y while the instrument is ON');
                else
                    app.Magn.y.EndSession();
                    app.Magn = rmfield(app.Magn, 'y');
                end
            end
        end

        % Value changed function: ConnectZ
        function ConnectZPushed(app, event)
            if app.ConnectZ.Value
                app.Magn.z= Keithley2200(PortMap('keithleyZ'));
            else
                if app.ZOutputOnOff.Value
                    app.ConnectZ.Value=1;
                    error('Cannot disconnect Z while the instrument is ON');
                else
                    app.Magn.z.EndSession();
                    app.Magn = rmfield(app.Magn, 'z');
                end
            end
        end

        % Close request function: MagnetLoLevel
        function MagnetLoLevelCloseRequest(app, event)
            
            choice=questdlg('All instruments will be switched OFF. Proceed?',...
                            'Close Experiment',...
                            'Yes','No','No');
            if strcmp(choice,'No')
                return
            end
            app.Timer.stop()
            if app.ConnectX.Value
                app.Magn.x.OutputOnOff(0);
                app.Magn.x.EndSession();
                app.Magn = rmfield(app.Magn, 'x');
            end
            if app.ConnectY.Value
                app.Magn.y.OutputOnOff(0);
                app.Magn.y.EndSession();
                app.Magn = rmfield(app.Magn, 'y');
            end
            if app.ConnectZ.Value
                app.Magn.z.OutputOnOff(0);
                app.Magn.z.EndSession();
                app.Magn = rmfield(app.Magn, 'z');
            end
            
            delete(app);
            disp('Live long and prosper!!')
        end

        % Value changed function: XOutputOnOff
        function XOutputOnOffValueChanged(app, event)
            
            value = app.XOutputOnOff.Value;
            if not(app.ConnectX.Value)
                app.XOutputOnOff.Value= not(value);
                updateXLamp();
                error('Not connected to the instrument X');
            end
            
            try
                app.Magn.x.OutputOnOff(value);
                updateXLamp();
            catch ME
                app.XOutputOnOff.Value=app.Magn.x.state;
                updateXLamp();
                rethrow(ME);
            end
            
            function updateXLamp()
                switch app.Magn.x.state
                    case 0
                        app.XLamp.Color=[0.9373 0.9373 0.9373];
                    case 1
                        app.XLamp.Color='green';
                end
            end
        end

        % Value changed function: YOutputOnOff
        function YOutputOnOffValueChanged(app, event)
            
            value = app.YOutputOnOff.Value;
            
            if not(app.ConnectY.Value)
                app.YOutputOnOff.Value= not(value);
                updateYLamp();
                error('Not connected to the instrument Y');
            end
            
            try
                app.Magn.y.OutputOnOff(value);
                updateYLamp();
            catch ME
                app.YOutputOnOff.Value=app.Magn.y.state;
                updateYLamp();
                rethrow(ME);
            end
            
            function updateYLamp()
                switch app.Magn.y.state
                    case 0
                        app.YLamp.Color=[0.9373 0.9373 0.9373];
                    case 1
                        app.YLamp.Color='green';
                end
            end
        end

        % Value changed function: ZOutputOnOff
        function ZOutputOnOffValueChanged(app, event)
            
            value = app.ZOutputOnOff.Value;
            if not(app.ConnectZ.Value)
                app.ZOutputOnOff.Value= not(value);
                updateZLamp(Z);
                error('Not connected to instrument Z');
            end
            
            try
                app.Magn.z.OutputOnOff(value);
                updateZLamp();
            catch ME
                app.ZOutputOnOff.Value=app.Magn.z.state;
                updateZLamp();
                rethrow(ME);
            end
            
            function updateZLamp()
                switch app.Magn.z.state
                    case 0
                        app.ZLamp.Color=[0.9373 0.9373 0.9373];
                    case 1
                        app.ZLamp.Color='green';
                end
            end
        end

        % Value changed function: XCurrentEdit
        function XCurrentEditValueChanged(app, event)
            value = app.XCurrentEdit.Value;
            if not(app.ConnectX.Value)
                error('Not connected to the instrument X');
            else
                
                app.Magn.x.ISet(value);
                if app.Magn.ThermalVoltage
                    set(app.XVoltageEdit,'Value',value*app.Magn.Rmax);
                    app.Magn.x.VSet(value*app.Magn.Rmax);
                end
            end
        end

        % Value changed function: XVoltageEdit
        function XVoltageEditValueChanged(app, event)
            value = app.XVoltageEdit.Value;
            if not(app.ConnectX.Value)
                error('Not connected to the instrument X');
            else
                
                app.Magn.x.VSet(value);
            end
        end

        % Value changed function: YCurrentEdit
        function YCurrentEditValueChanged(app, event)
            value = app.YCurrentEdit.Value;
            if not(app.ConnectY.Value)
                error('Not connected to the instrument Y');
            else
                
                app.Magn.y.ISet(value);
                if app.Magn.ThermalVoltage
                    set(app.YVoltageEdit, 'Value', value*app.Magn.Rmax);
                    app.Magn.y.VSet(value*app.Magn.Rmax);
                end
            end
        end

        % Value changed function: YVoltageEdit
        function YVoltageEditValueChanged(app, event)
            value = app.YVoltageEdit.Value;
            if not(app.ConnectY.Value)
                error('Not connected to the instrument Y');
            else
                
                app.Magn.y.VSet(value);
            end            
        end

        % Value changed function: ZVoltageEdit
        function ZVoltageEditValueChanged(app, event)
            value = app.ZVoltageEdit.Value;
            if not(app.ConnectZ.Value)
                error('Not connected to the instrument Z');
            else
                
                app.Magn.z.VSet(value);
            end            
        end

        % Value changed function: ZCurrentEdit
        function ZCurrentEditValueChanged(app, event)
            value = app.ZCurrentEdit.Value;
            if not(app.ConnectZ.Value)
                error('Not connected to the instrument Z');
            else
                
                app.Magn.z.ISet(value);
                if app.Magn.ThermalVoltage
                    set(app.ZVoltageEdit,'Value',value*app.Magn.Rmax);
                    app.Magn.z.VSet(value*app.Magn.Rmax);
                end
            end
        end

        % Value changed function: ThermalvoltageSwitch
        function ThermalvoltageSwitchValueChanged(app, event)
            value = app.ThermalvoltageSwitch.Value;
            
            if strcmp(value,'Off')
                app.Magn.ThermalVoltage=0;
            else 
                app.Magn.ThermalVoltage=1;
            end
            if app.Magn.ThermalVoltage
                state='off';
                if app.ConnectX.Value   
                    app.Magn.x.VSet(app.XCurrentEdit.Value*app.Magn.Rmax);
                end
                if app.ConnectY.Value 
                    app.Magn.y.VSet(app.YCurrentEdit.Value*app.Magn.Rmax);
                end
                if app.ConnectZ.Value
                    app.Magn.z.VSet(app.ZCurrentEdit.Value*app.Magn.Rmax);
                end
                set(app.XVoltageEdit,'Value',app.XCurrentEdit.Value*app.Magn.Rmax);
                
                set(app.YVoltageEdit,'Value',app.YCurrentEdit.Value*app.Magn.Rmax);
                set(app.ZVoltageEdit,'Value',app.ZCurrentEdit.Value*app.Magn.Rmax);
            else 
                state='on';
            end
            set(app.XVoltageEdit, 'enable', state);
            set(app.YVoltageEdit, 'enable', state);
            set(app.ZVoltageEdit, 'enable', state);
        end

        % Value changed function: UpdateSwitch
        function UpdateSwitchValueChanged(app, event)
            switch app.UpdateSwitch.Value
                case 'On'
                    disp('Starting Update Now!')
                    app.Timer.start()
                case 'Off'
                    
                    stop(app.Timer)
                    disp('Update Stopped!')
            end
        end

        % Button pushed function: RampscheduleButton
        function RampscheduleButtonPushed(app, event)
           
            if not(app.ConnectY.Value) || not(app.ConnectY.Value) || not(app.ConnectZ.Value)
                error('All coils must be connected to perform ramp schedule.')
            end
            
            app.Magn.x.Query();
            app.Magn.y.Query();
            app.Magn.z.Query();
            xcurrent= app.Magn.x.currentSet;
            xvoltage= app.Magn.x.voltageSet;            
            ycurrent= app.Magn.y.currentSet;
            yvoltage= app.Magn.y.voltageSet;
            zcurrent= app.Magn.z.currentSet;
            zvoltage= app.Magn.z.voltageSet;
            app.Magn.x.VSet(3.5*app.Magn.Rmax);
            app.Magn.y.VSet(3.5*app.Magn.Rmax);
            app.Magn.z.VSet(3.5*app.Magn.Rmax);
            
            app.Magn.x.ISet(0);
            app.Magn.y.ISet(0);
            app.Magn.z.ISet(0);
            pause(.5);
            app.Magn.x.ISet(3.5);
            app.Magn.y.ISet(3.5);
            app.Magn.z.ISet(3.5);
            pause(.5);
            app.Magn.x.ISet(0);
            app.Magn.y.ISet(0);
            app.Magn.z.ISet(0);
            pause(.5);
            app.Magn.x.ISet(3.5);
            app.Magn.y.ISet(3.5);
            app.Magn.z.ISet(3.5);
            pause(.5);
            app.Magn.x.ISet(xcurrent);
            app.Magn.y.ISet(ycurrent);
            app.Magn.z.ISet(zcurrent);
            
            app.Magn.x.VSet(xvoltage);
            app.Magn.y.VSet(yvoltage);
            app.Magn.z.VSet(zvoltage);
            disp(['Ramp complete at (' , num2str(xcurrent),', ',num2str(ycurrent),', ',num2str(zcurrent),')! Enjoy your magnetic field!'])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MagnetLoLevel = uifigure('Visible', 'off');
            app.MagnetLoLevel.Name = 'Magnet Low Level';
            app.MagnetLoLevel.CloseRequestFcn = createCallbackFcn(app, @MagnetLoLevelCloseRequest, true);
            app.XCoilPanel = uipanel(app.MagnetLoLevel);
            app.XCoilPanel.Title = 'X Coil';
            app.ConnectX = uibutton(app.XCoilPanel, 'state');
            app.ConnectX.ValueChangedFcn = createCallbackFcn(app, @ConnectXPushed, true);
            app.ConnectX.Text = 'Connect X';
            app.XOutputOnOff = uibutton(app.XCoilPanel, 'state');
            app.XOutputOnOff.ValueChangedFcn = createCallbackFcn(app, @XOutputOnOffValueChanged, true);
            app.XOutputOnOff.Text = 'Output On/Off';
            app.XLamp = uilamp(app.XCoilPanel);
            app.CurrentEditFieldLabel = uilabel(app.XCoilPanel);
            app.CurrentEditFieldLabel.Text = 'Current';
            app.XCurrent = uieditfield(app.XCoilPanel, 'numeric');
            app.XCurrent.Limits = [0 5];
            app.XCurrent.ValueDisplayFormat = '%.4f';
            app.XCurrent.Editable = 'off';
            app.ModeEditFieldLabel = uilabel(app.XCoilPanel);
            app.ModeEditFieldLabel.Text = 'Mode';
            app.XMode = uieditfield(app.XCoilPanel, 'text');
            app.XMode.Editable = 'off';
            app.PolaritySwitchLabel = uilabel(app.XCoilPanel);
            app.PolaritySwitchLabel.Text = 'Polarity';
            app.XPolaritySwitch = uiswitch(app.XCoilPanel, 'slider');
            app.XPolaritySwitch.Items = {'(-)', '(+)'};
            app.XPolaritySwitch.Value = '(-)';
            app.CurrentSetEditFieldLabel = uilabel(app.XCoilPanel);
            app.CurrentSetEditFieldLabel.Text = 'Current Set';
            app.XCurrentEdit = uieditfield(app.XCoilPanel, 'numeric');
            app.XCurrentEdit.Limits = [0 5];
            app.XCurrentEdit.ValueDisplayFormat = '%.4f';
            app.XCurrentEdit.ValueChangedFcn = createCallbackFcn(app, @XCurrentEditValueChanged, true);
            app.VoltageSetEditFieldLabel = uilabel(app.XCoilPanel);
            app.VoltageSetEditFieldLabel.Text = 'Voltage Set';
            app.XVoltageEdit = uieditfield(app.XCoilPanel, 'numeric');
            app.XVoltageEdit.Limits = [0 30];
            app.XVoltageEdit.ValueDisplayFormat = '%.3f';
            app.XVoltageEdit.ValueChangedFcn = createCallbackFcn(app, @XVoltageEditValueChanged, true);
            app.VoltageEditFieldLabel = uilabel(app.XCoilPanel);
            app.VoltageEditFieldLabel.Text = 'Voltage';
            app.XVoltage = uieditfield(app.XCoilPanel, 'numeric');
            app.XVoltage.Limits = [0 30];
            app.XVoltage.ValueDisplayFormat = '%.3f';
            app.XVoltage.Editable = 'off';
            app.YCoilPanel = uipanel(app.MagnetLoLevel);
            app.YCoilPanel.Title = 'Y Coil';
            app.ConnectY = uibutton(app.YCoilPanel, 'state');
            app.ConnectY.ValueChangedFcn = createCallbackFcn(app, @ConnectYPushed, true);
            app.ConnectY.Text = 'Connect Y';
            app.YOutputOnOff = uibutton(app.YCoilPanel, 'state');
            app.YOutputOnOff.ValueChangedFcn = createCallbackFcn(app, @YOutputOnOffValueChanged, true);
            app.YOutputOnOff.Text = 'Output On/Off';
            app.YLamp = uilamp(app.YCoilPanel);
            app.XVoltageEditField_2Label = uilabel(app.YCoilPanel);
            app.XVoltageEditField_2Label.Text = 'Voltage Set';
            app.YVoltageEdit = uieditfield(app.YCoilPanel, 'numeric');
            app.YVoltageEdit.Limits = [0 30];
            app.YVoltageEdit.ValueDisplayFormat = '%.3f';
            app.YVoltageEdit.ValueChangedFcn = createCallbackFcn(app, @YVoltageEditValueChanged, true);
            app.XCurrentEditField_2Label = uilabel(app.YCoilPanel);
            app.XCurrentEditField_2Label.Text = 'Current Set';
            app.YCurrentEdit = uieditfield(app.YCoilPanel, 'numeric');
            app.YCurrentEdit.Limits = [0 5];
            app.YCurrentEdit.ValueDisplayFormat = '%.4f';
            app.YCurrentEdit.ValueChangedFcn = createCallbackFcn(app, @YCurrentEditValueChanged, true);
            app.XVoltage_2Label = uilabel(app.YCoilPanel);
            app.XVoltage_2Label.Text = 'Voltage';
            app.YVoltage = uieditfield(app.YCoilPanel, 'numeric');
            app.YVoltage.Limits = [0 30];
            app.YVoltage.ValueDisplayFormat = '%.3f';
            app.YVoltage.Editable = 'off';
            app.XCurrent_2Label = uilabel(app.YCoilPanel);
            app.XCurrent_2Label.Text = 'Current';
            app.YCurrent = uieditfield(app.YCoilPanel, 'numeric');
            app.YCurrent.Limits = [0 5];
            app.YCurrent.ValueDisplayFormat = '%.4f';
            app.YCurrent.Editable = 'off';
            app.XMode_2Label = uilabel(app.YCoilPanel);
            app.XMode_2Label.Text = 'Mode';
            app.YMode = uieditfield(app.YCoilPanel, 'text');
            app.YMode.Editable = 'off';
            app.PolaritySwitchLabel_2 = uilabel(app.YCoilPanel);
            app.PolaritySwitchLabel_2.Text = 'Polarity';
            app.YPolaritySwitch = uiswitch(app.YCoilPanel, 'slider');
            app.YPolaritySwitch.Items = {'(-)', '(+)'};
            app.YPolaritySwitch.Value = '(-)';
            app.ZCoilPanel = uipanel(app.MagnetLoLevel);
            app.ZCoilPanel.Title = 'Z Coil';
            app.ConnectZ = uibutton(app.ZCoilPanel, 'state');
            app.ConnectZ.ValueChangedFcn = createCallbackFcn(app, @ConnectZPushed, true);
            app.ConnectZ.Text = 'Connect Z';
            app.PolaritySwitchLabel_3 = uilabel(app.ZCoilPanel);
            app.PolaritySwitchLabel_3.Text = 'Polarity';
            app.ZPolaritySwitch = uiswitch(app.ZCoilPanel, 'slider');
            app.ZPolaritySwitch.Items = {'(-)', '(+)'};
            app.ZPolaritySwitch.Value = '(-)';
            app.XVoltageEditField_3Label = uilabel(app.ZCoilPanel);
            app.XVoltageEditField_3Label.Text = 'Voltage Set';
            app.ZVoltageEdit = uieditfield(app.ZCoilPanel, 'numeric');
            app.ZVoltageEdit.Limits = [0 30];
            app.ZVoltageEdit.ValueDisplayFormat = '%.3f';
            app.ZVoltageEdit.ValueChangedFcn = createCallbackFcn(app, @ZVoltageEditValueChanged, true);
            app.XCurrentEditField_3Label = uilabel(app.ZCoilPanel);
            app.XCurrentEditField_3Label.Text = 'Current Set';
            app.ZCurrentEdit = uieditfield(app.ZCoilPanel, 'numeric');
            app.ZCurrentEdit.Limits = [0 5];
            app.ZCurrentEdit.ValueDisplayFormat = '%.4f';
            app.ZCurrentEdit.ValueChangedFcn = createCallbackFcn(app, @ZCurrentEditValueChanged, true);
            app.ZOutputOnOff = uibutton(app.ZCoilPanel, 'state');
            app.ZOutputOnOff.ValueChangedFcn = createCallbackFcn(app, @ZOutputOnOffValueChanged, true);
            app.ZOutputOnOff.Text = 'Output On/Off';
            app.ZLamp = uilamp(app.ZCoilPanel);
            app.XVoltage_3Label = uilabel(app.ZCoilPanel);
            app.XVoltage_3Label.Text = 'Voltage';
            app.ZVoltage = uieditfield(app.ZCoilPanel, 'numeric');
            app.ZVoltage.Limits = [0 30];
            app.ZVoltage.ValueDisplayFormat = '%.3f';
            app.ZVoltage.Editable = 'off';
            app.XCurrent_3Label = uilabel(app.ZCoilPanel);
            app.XCurrent_3Label.Text = 'Current';
            app.ZCurrent = uieditfield(app.ZCoilPanel, 'numeric');
            app.ZCurrent.Limits = [0 5];
            app.ZCurrent.ValueDisplayFormat = '%.4f';
            app.ZCurrent.Editable = 'off';
            app.XMode_3Label = uilabel(app.ZCoilPanel);
            app.XMode_3Label.Text = 'Mode';
            app.ZMode = uieditfield(app.ZCoilPanel, 'text');
            app.ZMode.Editable = 'off';
            app.RampscheduleButton = uibutton(app.MagnetLoLevel, 'push');
            app.RampscheduleButton.ButtonPushedFcn = createCallbackFcn(app, @RampscheduleButtonPushed, true);
            app.RampscheduleButton.Text = 'Ramp schedule';
            app.ThermalvoltageSwitchLabel = uilabel(app.MagnetLoLevel);
            app.ThermalvoltageSwitchLabel.Text = 'Thermal voltage?';
            app.ThermalvoltageSwitch = uiswitch(app.MagnetLoLevel, 'slider');
            app.ThermalvoltageSwitch.ValueChangedFcn = createCallbackFcn(app, @ThermalvoltageSwitchValueChanged, true);
            app.UpdateSwitchLabel = uilabel(app.MagnetLoLevel);
            app.UpdateSwitchLabel.Text = 'Update';
            app.UpdateSwitch = uiswitch(app.MagnetLoLevel, 'slider');
            app.UpdateSwitch.ValueChangedFcn = createCallbackFcn(app, @UpdateSwitchValueChanged, true);
            app.LogPathEditFieldLabel = uilabel(app.MagnetLoLevel);
            app.LogPathEditFieldLabel.Text = 'Log Path';
            app.LogPathEditField = uieditfield(app.MagnetLoLevel, 'text');
            app.LogPathEditField.Value = 'C:\Data\Magnets_Log\';
            % Show the figure after all components are created
            app.MagnetLoLevel.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Magnet_New

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MagnetLoLevel)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MagnetLoLevel)
        end
    end
end